classdef reflex_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FolderEndingLabel      matlab.ui.control.Label
        FolderEndingEditField  matlab.ui.control.EditField
        StartButton            matlab.ui.control.Button
        PauseButton            matlab.ui.control.Button
        QuitButton             matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        LoadParametersButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        params  % parameters for the task and for the device
        folder_ending % user input from editField
        loaded_parameters
        adi_doc
        adi_gLCApp
        d128
        blockIndex
        secsPerTick
        msec_per_tick
        recording_ticks_before_stim
        recording_ticks_after_stim
        time_vector
        time_column 
        data_matrix
        stimNo
        current 
        step
        score 
        total_reflexes 
        currents_for_threshold 
        stim_no_column 
        current_column 
        score_column 
        total_reflexes_column 
        peri_stim_data
        z_score
        log_path
        stopped
        paused
    end
    % my functions
    methods (Access = public)
        
        function elapsedTime = inter_stim_interval(app,range_of_intervals)
            % selects a random interval from range_of_intervals,
            % pauses for that duration, and displays the elapsed time.
        
            % Select a random interval value from the given intervals
            random_interval = randsample(range_of_intervals, 1);  % Select a random interval value
            
            % Start the timer
            tic;
            
            % Pause for the randomly selected interval
            pause(random_interval);
            
            % Measure the elapsed time
            elapsedTime = toc;
            
            % Display the elapsed time
%             disp(['Interval time: ', num2str(elapsedTime), ' seconds']);
        end % inter_stim_interval
        
        function device = update_device_value(app,new_value,device)
            % upload new mAmp value to teh device
            % return device
            [success, device] = D128ctrl('enable', device, 0);
            [success, device] = D128ctrl('demand', device, new_value);
            % Upload all parameters to device
            success = D128ctrl('upload', device);
            [success, device] = D128ctrl('enable', device, 1); % make sure it is enabled
        end % update_device_value
        
        
        function is_updated = check_demand_value_on_device(app,device,demand_value,timeout)
            % downloads device status to check if demand value is on the device
            % tries timeout times to upload it, if it was not on the device
            % returns 1 for true and 0 for false
            
            % Download status from device
            [success, device] = D128ctrl('status', device);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % confirm that the parameters were loaded to the device
            if device.demand == demand_value*10
                is_updated = 1;
                return;
            else
                % try timeout times
                for i = 1:timeout
                    [success, device] = D128ctrl('demand', device, new_value);
                    % Upload all parameters to device
                    success = D128ctrl('upload', device);
                    pause(200);
                    % Download status from device
                    [success, device] = D128ctrl('status', device);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % confirm that the parameters were loaded to the device
                    if device.demand == demand_value*10
                        is_updated = 1;
                        return;
                    end
                end
                is_updated = 0;
            end   
        end % check_demand_value_on_device

        function peri_stim_window = get_peri_stim_data(app,labchart_file, stim_threshold, recording_ticks_before_stim, recording_ticks_after_stim)
            % returns data window around the stimulus
            % assumes the stimulus was on channel 2 and emg on channel 1
            nr = labchart_file.NumberOfRecords;
            chan1 = labchart_file.GetChannelData(1,1,nr,1,-1); % channel 1 recent block data (emg with response)
            chan2 = labchart_file.GetChannelData(1,2,nr,1,-1); % channel 2 recent block data (stim channel)
            index_of_stim = find(chan2 > stim_threshold, 1);  % Finds the first index where value is > 0.1
            % keep relevant information in the other channel
            % Calculate the range of indices to extract
            start_index = max(index_of_stim - recording_ticks_before_stim, 1);  % Ensure we don't go below 1
            end_index = min(index_of_stim + recording_ticks_after_stim, length(chan1)); % Ensure we don't exceed vector length
        
            % Extract the segment of data
            peri_stim_window = chan1(start_index:end_index);
        end % get_peri_stim_data

        function z_score = calculate_zscore(app,data_window,time_window,baseline_window_begin,baseline_window_end,reflex_window_begin, reflex_window_end)
            % find beseline data to calculate mean and standard deviation
            % find zscore value in the window after stimulus
            
            % baseline
            baseline_data_indexes = (time_window >= baseline_window_begin) & (time_window <= baseline_window_end);
            % Select the values in data_window that correspond to the time_window range
            baseline_data = data_window(baseline_data_indexes);
            baseline_mean = mean(baseline_data);
            baseline_stdev = std(baseline_data);
            
            % reflex
            reflex_data_indexes = (time_window >= reflex_window_begin) & (time_window <= reflex_window_end);
            reflex_data = data_window(reflex_data_indexes);
            peak_reflex = max(reflex_data);
            z_score = (peak_reflex-baseline_mean)/baseline_stdev;
        end % z_score
        
        function create_task_subfolder(app,log_path,subfolder_name)
            % create subfolder for the task log
            task_name_folder = strcat(char(datetime('now', 'Format', 'yyyy_MM_dd-HH_mm')),'-',subfolder_name);
            subfolder_path = fullfile(log_path,task_name_folder);
            % Check if the folder exists
            if exist(subfolder_path, 'dir') ~= 7
                % If the folder does not exist, create it
                mkdir(subfolder_path);
                disp(['Folder created: ', subfolder_path]);
            else
                % If the folder exists, display a message
                disp(['Folder already exists: ', subfolder_path]);
            end
        end % create_task_subfolder
        
        function save_emg_data(app,log_path,subfolder_name,data_matrix,stimNo)
            % Save emg channel data from all stimuli
            task_name_folder = strcat(char(datetime('now', 'Format', 'yyyy_MM_dd-HH_mm')),'-',subfolder_name);
            filename = strcat(task_name_folder, '-peri_stim.txt');
            file_path = fullfile(log_path,task_name_folder,filename);
            % Open the file for writing
            fileID = fopen(file_path, 'w');
            % Write the header row with "Time (ms)" and subsequent "Stim_###" labels
            fprintf(fileID, 'Time (ms)'); % first column header
            % Generate headers for subsequent columns
            for i = 1:stimNo
                % Create the header for each subsequent column, formatted as "Stim_###"
                fprintf(fileID, '\tStim_%03d', i); % tab-separated, with 3-digit zero-padded numbers
            end
            % New line after header
            fprintf(fileID, '\n');
            % Close the file (this saves the header)
            fclose(fileID);
            % Append the matrix data to the file as tab-separated values
            writematrix(data_matrix, file_path, 'FileType', 'text', 'Delimiter', '\t', 'WriteMode', 'append');
        end % save_emg_data
        
        function save_scores(app,log_path,subfolder_name,currents_for_threshold,stim_no_column,current_column,score_column,total_reflexes_column)
            task_name_folder = strcat(char(datetime('now', 'Format', 'yyyy_MM_dd-HH_mm')),'-',subfolder_name);
            % save scores
            filename = strcat(task_name_folder,'-threshold.txt');
            file_path = fullfile(log_path,task_name_folder,filename);
            % Open the file for writing
            fileID = fopen(file_path, 'w');
            
            if length(currents_for_threshold) > 0
                calculated_threshold = mean(currents_for_threshold);
                fprintf(fileID, ['Threshold: ', num2str(calculated_threshold), ' mA\n\n\n']);
            end
                
            % Write the header row with the correct formatting and fixed widths
            fprintf(fileID, '%-10s\t%-12s\t%-6s\t%-22s\n', 'Stim No:', 'Current (mA):', 'Score:', 'Total Reflexes:');
            
            % Convert the columns to column vectors to ensure consistency
            stim_no_column = stim_no_column(:);
            current_column = current_column(:);
            score_column = score_column(:);
            total_reflexes_column = total_reflexes_column(:);
            
            % Round the score column to 3 decimal places
            score_column = round(score_column, 3);
            
            % Loop through the data and write it row by row with fixed-width formatting
            for i = 1:length(stim_no_column)
                fprintf(fileID, '%-10d\t%-12.1f\t%-6.3f\t%-22d\n', stim_no_column(i), current_column(i), score_column(i), total_reflexes_column(i));
            end
            
            % Close the file after writing
            fclose(fileID);
        end % save_scores
        
        function save_adi(app,log_path,subfolder_name,adi_doc,adi_gLCApp)
            task_name_folder = strcat(char(datetime('now', 'Format', 'yyyy_MM_dd-HH_mm')),'-',subfolder_name);
            LC_file_name = strcat(task_name_folder,"-log.adicht");
            adi_doc.SaveAs(fullfile(log_path,task_name_folder,LC_file_name));
            adi_doc.Close;
            adi_doc.release;
            adi_gLCApp.release;
            system('taskkill /F /IM LabChart8.exe');
        end % save_adi
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartButton.Enable = 'off';
            app.QuitButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.loaded_parameters = 0;
            app.TextArea.Value = 'Enter Folder Ending';
            app.TextArea.Value{end+1} = 'Then Press Load Parameters Button';
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)

            % Get the value from the Edit Field
            app.folder_ending = strtrim(app.FolderEndingEditField.Value);  
            
            if length(app.folder_ending) == 0
                app.TextArea.Value = 'Please Enter Folder Ending';
            else
                % Get constants from settings function
                app.params = my_settings();
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % SET UP LOGGING
                % Specify the folder path
                app.log_path = fullfile(app.params.CURRENT_PATH,app.params.LOG_FOLDER);
                % Check if the folder exists
                if exist(app.log_path, 'dir') ~= 7
                    % If the folder does not exist, create it
                    mkdir(app.log_path);
                    disp(['Folder created: ', app.log_path]);
                else
                    % If the folder exists, display a message
                    disp(['Folder already exists: ', app.log_path]);
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % SET UP THE STIMULATOR
                % connect digitimer to channel 2 in PowerLab!
                % open device and return handle for further calls
                [success, app.d128] = D128ctrl('open');
                % Download status from device and initial d128 object parameters
                [success, app.d128] = D128ctrl('status', app.d128);
                [success, app.d128] = D128ctrl('enable', app.d128, 0);
                % Set value of pulsewidth (does not upload to device)
                [success, app.d128] = D128ctrl('source', app.d128, 'Internal');
                [success, app.d128] = D128ctrl('pulsewidth', app.d128, app.params.PULSEWIDTH);
                [success, app.d128] = D128ctrl('demand', app.d128, app.params.INIT_mAMP);
                [success, app.d128] = D128ctrl('dwell', app.d128, app.params.DWELL);
                % Upload all parameters to device
                success = D128ctrl('upload', app.d128);
                [success, app.d128] = D128ctrl('enable', app.d128, 1); % make sure it is enabled
                % Download status from device
                [success, app.d128] = D128ctrl('status', app.d128);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % confirm that the parameters were loaded to the device
                if app.d128.pulsewidth == app.params.PULSEWIDTH & app.d128.demand == app.params.INIT_mAMP*10
                    app.loaded_parameters = 1;
                    app.TextArea.Value = 'SUCCESS. Parameters loaded';
                    app.TextArea.Value{end+1} = '';
                    app.TextArea.Value{end+1} = 'Press Start button to start the Experiment';
                else
                    app.loaded_parameters = 0;
                    app.TextArea.Value = 'FAILED to load Parameters on the device';
                    return;
                end
                    % Disable some buttons
                    app.LoadParametersButton.Enable = 'off';
                    app.QuitButton.Enable = 'off';
                    app.PauseButton.Enable = 'off';
                    % Start LabChart
                    app.adi_doc = actxserver("ADIChart.Document");
                    app.adi_gLCApp = app.adi_doc.Application;
                    app.adi_doc.Close();
                    % Open the file specified by path, and return a reference to that LabChart adi.document.
                    app.adi_doc = app.adi_gLCApp.Open(app.params.PATH2TEMPLATE);
                    app.blockIndex = 0;
                    app.secsPerTick = app.adi_doc.GetRecordSecsPerTick(app.blockIndex);
                    app.msec_per_tick = app.secsPerTick *1000; % get how many ms is one tick
                    app.recording_ticks_before_stim = floor(app.params.RECORDING_MS_BEFORE_STIM/app.msec_per_tick); % how many ticks is my before window
                    app.recording_ticks_after_stim = floor(app.params.RECORDING_MS_AFTER_STIM/app.msec_per_tick);   % how many ticks is my after window
                    % create time vector for that
                    app.time_vector = (-app.recording_ticks_before_stim : app.recording_ticks_after_stim) * app.msec_per_tick;
                    % Convert to column vector
                    app.time_column = app.time_vector(:); % the colon operator (:) converts to a column vector
                    % Initialize the matrix with the first column
                    app.data_matrix = app.time_column;
                    
                    app.stimNo = 0;
                    app.current = app.params.INIT_mAMP;
                    app.step = app.params.STEP_UP_BIG_mAMP;
                    app.score = 0;
                    app.total_reflexes = 0;
                    app.currents_for_threshold = [];
                    app.stim_no_column = [];
                    app.current_column = [];
                    app.score_column = [];
                    app.total_reflexes_column = [];
                    app.stopped = 0;
                    app.paused = 0;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.loaded_parameters
                app.StartButton.Enable = 'off';
                app.QuitButton.Enable = 'on';
                app.PauseButton.Enable = 'on';
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % main program loop that will keep delivering the stimuli
                while (app.current >= app.params.INIT_mAMP && app.current <= app.params.MAX_mAMP) && app.total_reflexes < app.params.MAX_REFLEXES
                    if app.stopped 
                        break
                    end
                    % Check if paused
                    while app.paused
                        pause(0.1);  % Pause briefly to allow GUI to respond
                    end
                    app.stimNo = app.stimNo + 1;
                    app.TextArea.Value{end+1} = '';
                    app.TextArea.Value{end+1} =['Stimulus number: ', num2str(app.stimNo)];
                    app.TextArea.Value{end+1} = ['Current: ', num2str(app.current), ' mA'];
    %                 fprintf(['\n\nStimulus number: ', num2str(stimNo),'\n']);
    %                 disp(['Current: ', num2str(current), ' mA']);
                    % wait inter stimulus interval
                    interval = app.inter_stim_interval(app.params.INTER_STIM_INTERVALS);
                    app.TextArea.Value{end+1} = ['Interval: ', num2str(interval), ' seconds'];
                    % start recording
                    app.adi_doc.StartSampling;
                    pause((app.params.RECORDING_MS_BEFORE_STIM+500)/1000) % how much to wait before the stim (add some tolerance:500ms)
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % trigger the device
                    success = D128ctrl('Trigger', app.d128);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    pause((app.params.RECORDING_MS_AFTER_STIM+500)/1000)   % how much to wait after the stim before stopping the recording (add some tolerance:500ms)
                
                    app.adi_doc.StopSampling;
                    
                    % get peri stim data
                    app.peri_stim_data = app.get_peri_stim_data(app.adi_doc, app.params.STIM_THRESHOLD_V,app.recording_ticks_before_stim, app.recording_ticks_after_stim);
                    % calculate zscore
                    app.z_score = app.calculate_zscore(app.peri_stim_data,app.time_vector,app.params.BASELINE_WINDOW_BEGIN_MS,app.params.BASELINE_WINDOW_END_MS,app.params.REFLEX_WINDOW_BEGIN_MS,app.params.REFLEX_WINDOW_END_MS);
                    app.TextArea.Value{end+1} = ['Z-score: ', num2str(app.z_score)];
                    
                    if app.z_score > app.params.Z_SCORE_THRESHOLD
                        % if it is the first reflex or z-score was above the threshold when
                        % increasing current, count it as a reflex and
                        % increment the total reflexes
                        if sum(app.total_reflexes_column) == 0 || app.step > 0
                            app.total_reflexes = app.total_reflexes + 1;
                            app.currents_for_threshold = [app.currents_for_threshold,app.current];
                        end
                        app.step = app.params.STEP_DOWN_mAMP;
                    else % no reflex
                       if sum(app.total_reflexes_column) == 0 
                           % if there were no reflexes before, go up faster
                           app.step = app.params.STEP_UP_BIG_mAMP;
                       else
                           % if there were any reflexes before, go up slower
                           app.step = app.params.STEP_UP_mAMP;
                       end 
                    end
                    app.TextArea.Value{end+1} = ['Total reflexes: ', num2str(app.total_reflexes)];
                    % Append the new column to the data_matrix
                    % Convert to column vector
                    app.peri_stim_data = app.peri_stim_data(:); % the colon operator (:) converts to a column vector
                    app.data_matrix = [app.data_matrix, app.peri_stim_data];
                    % add logging info
                    app.stim_no_column = [app.stim_no_column,app.stimNo];
                    app.current_column = [app.current_column,app.current];
                    app.score_column = [app.score_column,app.z_score];
                    app.total_reflexes_column = [app.total_reflexes_column,app.total_reflexes];
                    app.current = app.current+app.step;
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Upload new values to the device
                    app.d128 = app.update_device_value(app.current,app.d128);
                    % Make sure it is loaded to the device
                    is_updated = app.check_demand_value_on_device(app.d128,app.current,3);
                    if ~is_updated
                        app.TextArea.Value = 'Could not upload new value to the device';
%                         disp('Could not upload new value to the device');
                        return;
                    end
                 end % end while loop
                 
                  % Show threshold in the test area
                 if length(app.currents_for_threshold) == 3
                     calculated_threshold = mean(app.currents_for_threshold);
                     app.TextArea.Value{end+1} = '';
                     app.TextArea.Value{end+1} = ['Threshold: ', num2str(calculated_threshold)];
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                 % Save the log files, close LabChart
                 app.create_task_subfolder(app.log_path,app.folder_ending);
                 app.save_emg_data(app.log_path,app.folder_ending,app.data_matrix,app.stimNo);
                 app.save_scores(app.log_path,app.folder_ending,app.currents_for_threshold,app.stim_no_column,app.current_column,app.score_column,app.total_reflexes_column)
                 app.save_adi(app.log_path,app.folder_ending,app.adi_doc,app.adi_gLCApp)
            else
                app.TextArea.Value = 'First Press: Load Parameters Button';
            end % if parameters were loaded
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            if app.loaded_parameters % only after the parameters are loaded
                app.stopped = 1;
                app.QuitButton.Enable = 'off';
                app.PauseButton.Enable = 'off';
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % Toggle the paused state
            app.paused = ~app.paused;
            
            % Update the button text
            if app.paused
                app.PauseButton.Text = 'Resume';
                app.QuitButton.Enable = 'off';
            else
                app.PauseButton.Text = 'Pause';
                app.QuitButton.Enable = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if app.loaded_parameters == 1 % save logs and close labChart only if parameters were loaded
                % Save the log files, close LabChart
                app.create_task_subfolder(app.log_path,app.folder_ending);
                app.save_emg_data(app.log_path,app.folder_ending,app.data_matrix,app.stimNo);
                app.save_scores(app.log_path,app.folder_ending,app.currents_for_threshold,app.stim_no_column,app.current_column,app.score_column,app.total_reflexes_column)
                app.save_adi(app.log_path,app.folder_ending,app.adi_doc,app.adi_gLCApp)
                end
            catch
                % do nothing
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FolderEndingLabel = uilabel(app.UIFigure);
            app.FolderEndingLabel.Text = 'Folder Ending:';
            app.FolderEndingEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.TextArea = uitextarea(app.UIFigure);
            app.LoadParametersButton = uibutton(app.UIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reflex_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end